classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        WybierzcochceszwywietliLabel    matlab.ui.control.Label
        WybierzcochceszwywietliListBox  matlab.ui.control.ListBox
        WartocielementwukaduLabel       matlab.ui.control.Label
        R1EditField_5Label              matlab.ui.control.Label
        R1EditField_5                   matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        WartodowywietleniaLabel         matlab.ui.control.Label
        AmplitudaEditFieldLabel         matlab.ui.control.Label
        AmplitudaEditField              matlab.ui.control.NumericEditField
        AktualizujwykresButton          matlab.ui.control.Button
        WymuszenieLabel                 matlab.ui.control.Label
        StaaCheckBox                    matlab.ui.control.CheckBox
        CosCheckBox                     matlab.ui.control.CheckBox
        ExpCheckBox                     matlab.ui.control.CheckBox
        StaaEditFieldLabel              matlab.ui.control.Label
        StaaEditField                   matlab.ui.control.NumericEditField
        StaadlaExpEditFieldLabel        matlab.ui.control.Label
        StaadlaExpEditField             matlab.ui.control.NumericEditField
        PotgaEditFieldLabel             matlab.ui.control.Label
        PotgaEditField                  matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        A
        R1
        R2
        L
        w
        fi
        S
        SE
        P
    end
    
    methods (Access = public)
        
        function [U2,UR1,UL,I] = obliczRLR(~,U1,R1,R2,L)
            syms U2s UR1s ULs Is s;

            U1s=laplace(U1);
    
            U2s=solve((U2s==(R2/(R1+R2+s*L)*U1s)),U2s);
            U2=ilaplace(U2s);

            UR1s=solve((UR1s==(R1/(R1+R2+s*L)*U1s)),UR1s);
            UR1=ilaplace(UR1s);

            ULs=solve((ULs==((L*s)/(R1+R2+s*L)*U1s)),ULs);
            UL=ilaplace(ULs);
            
            Is=solve((Is==(U1s/(R1+R2+s*L))),Is);
            I=ilaplace(Is);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {553, 553};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {440, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AktualizujwykresButton
        function AktualizujwykresButtonPushed(app, event)
            syms t;
            app.A=app.AmplitudaEditField.Value;
            app.R1=app.R1EditField_5.Value;
            app.R2=app.R2EditField.Value;
            app.L=app.LEditField.Value;
            app.w=app.EditField_4.Value;
            app.fi=app.EditField_3.Value;
            app.S=app.StaaEditField.Value;
            app.SE=app.StaadlaExpEditField.Value;
            app.P=app.PotgaEditField.Value;
            U1(t)=app.S+app.A*cos(app.w*t+app.fi)+app.SE*exp(app.P*t);
            [U2(t),UR1(t),UL(t),I(t)] = obliczRLR(app,U1(t),app.R1,app.R2,app.L);

            if app.CosCheckBox.Value
                T=(2*pi)/app.w;
                t=linspace(0,7*T,1000);
            else
                tau=app.L/(app.R1+app.R2);
                t=linspace(0,10*tau,1000);
            end
            
            
            plot(app.UIAxes,t,U1(t));
            app.EditField.Value=string(U1);
            
            switch app.WybierzcochceszwywietliListBox.Value
                case 'Napięcie Wyjściowe'
                    plot(app.UIAxes2,t,U2(t));
                    app.UIAxes2.Title.String='Napięcie Wyjściowe';
                    app.UIAxes2.YLabel.String='U2(V)';
                    app.EditField_2.Value=string(U2);
                case 'Napięcie na R1'
                    plot(app.UIAxes2,t,UR1(t));
                    app.UIAxes2.Title.String='Napięcie na R1';
                    app.UIAxes2.YLabel.String='UR1(V)';
                    app.EditField_2.Value=string(UR1);
                case 'Napięcie na cewce'
                    plot(app.UIAxes2,t,UL(t));
                    app.UIAxes2.Title.String='Napięcie na cewce';
                    app.UIAxes2.YLabel.String='UL(V)';
                    app.EditField_2.Value=string(UL);
                case 'Prąd w obwodzie'
                    plot(app.UIAxes2,t,I(t));
                    app.UIAxes2.Title.String='Prąd w obwodzie';
                    app.UIAxes2.YLabel.String='I(A)';
                    app.EditField_2.Value=string(I);
            end
        end

        % Value changed function: StaaCheckBox
        function StaaCheckBoxValueChanged(app, event)
            if app.StaaCheckBox.Value
                app.StaaEditField.Editable='on';
            else
                app.StaaEditField.Editable='off';
                app.StaaEditField.Value=0;
            end
            
        end

        % Value changed function: CosCheckBox
        function CosCheckBoxValueChanged(app, event)
            if app.CosCheckBox.Value
                app.AmplitudaEditField.Editable='on';
                app.EditField_4.Editable='on';
                app.EditField_3.Editable='on';
            else
                app.AmplitudaEditField.Editable='off';
                app.AmplitudaEditField.Value=0;
                app.EditField_4.Editable='off';
                app.EditField_4.Value=0;
                app.EditField_3.Editable='off';
                app.EditField_3.Value=0;
            end
            
        end

        % Value changed function: ExpCheckBox
        function ExpCheckBoxValueChanged(app, event)
            if app.ExpCheckBox.Value
                app.StaadlaExpEditField.Editable='on';
                app.PotgaEditField.Editable='on';
            else
                app.StaadlaExpEditField.Editable='off';
                app.StaadlaExpEditField.Value=0;
                app.PotgaEditField.Editable='off';
                app.PotgaEditField.Value=0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Napięcie Wejściowe')
            xlabel(app.UIAxes, 't(s)')
            ylabel(app.UIAxes, 'U1(V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Napięcie Wyjściowe')
            xlabel(app.UIAxes2, 't(s)')
            ylabel(app.UIAxes2, 'U2(V)')
            zlabel(app.UIAxes2, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.WybierzcochceszwywietliLabel = uilabel(app.RightPanel);
            app.WybierzcochceszwywietliLabel.Text = {'Wybierz'; 'co chcesz'; 'wyświetlić:'};
            app.WybierzcochceszwywietliListBox = uilistbox(app.RightPanel);
            app.WybierzcochceszwywietliListBox.Items = {'Napięcie Wyjściowe', 'Napięcie na R1', 'Napięcie na cewce', 'Prąd w obwodzie'};
            app.WybierzcochceszwywietliListBox.Value = 'Napięcie Wyjściowe';
            app.WartocielementwukaduLabel = uilabel(app.RightPanel);
            app.WartocielementwukaduLabel.Text = 'Wartości elementów układu';
            app.R1EditField_5Label = uilabel(app.RightPanel);
            app.R1EditField_5Label.Text = 'R1';
            app.R1EditField_5 = uieditfield(app.RightPanel, 'numeric');
            app.R2EditFieldLabel = uilabel(app.RightPanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.RightPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.RightPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField_4Label = uilabel(app.RightPanel);
            app.EditField_4Label.Text = 'ω';
            app.EditField_4 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_3Label = uilabel(app.RightPanel);
            app.EditField_3Label.Text = 'φ';
            app.EditField_3 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.WartodowywietleniaLabel = uilabel(app.RightPanel);
            app.WartodowywietleniaLabel.Text = 'Wartość do wyświetlenia';
            app.AmplitudaEditFieldLabel = uilabel(app.RightPanel);
            app.AmplitudaEditFieldLabel.Text = 'Amplituda';
            app.AmplitudaEditField = uieditfield(app.RightPanel, 'numeric');
            app.AmplitudaEditField.Editable = 'off';
            app.AktualizujwykresButton = uibutton(app.RightPanel, 'push');
            app.AktualizujwykresButton.ButtonPushedFcn = createCallbackFcn(app, @AktualizujwykresButtonPushed, true);
            app.AktualizujwykresButton.Text = 'Aktualizuj wykres';
            app.WymuszenieLabel = uilabel(app.RightPanel);
            app.WymuszenieLabel.Text = 'Wymuszenie';
            app.StaaCheckBox = uicheckbox(app.RightPanel);
            app.StaaCheckBox.ValueChangedFcn = createCallbackFcn(app, @StaaCheckBoxValueChanged, true);
            app.StaaCheckBox.Text = 'Stała';
            app.CosCheckBox = uicheckbox(app.RightPanel);
            app.CosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CosCheckBoxValueChanged, true);
            app.CosCheckBox.Text = 'Cos';
            app.ExpCheckBox = uicheckbox(app.RightPanel);
            app.ExpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExpCheckBoxValueChanged, true);
            app.ExpCheckBox.Text = 'Exp';
            app.StaaEditFieldLabel = uilabel(app.RightPanel);
            app.StaaEditFieldLabel.Text = 'Stała';
            app.StaaEditField = uieditfield(app.RightPanel, 'numeric');
            app.StaaEditField.Editable = 'off';
            app.StaadlaExpEditFieldLabel = uilabel(app.RightPanel);
            app.StaadlaExpEditFieldLabel.Text = 'Stała dla Exp';
            app.StaadlaExpEditField = uieditfield(app.RightPanel, 'numeric');
            app.StaadlaExpEditField.Editable = 'off';
            app.PotgaEditFieldLabel = uilabel(app.RightPanel);
            app.PotgaEditFieldLabel.Text = 'Potęga';
            app.PotgaEditField = uieditfield(app.RightPanel, 'numeric');
            app.PotgaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end